classdef adjustcontrastGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        ImageAxes3  matlab.ui.control.UIAxes
        ImageAxes2  matlab.ui.control.UIAxes
        ImageAxes1  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        im1;
        im2;
        im3;
        lowhigh;
        ich;
    end

    methods (Access = public)
        function updatelowhigh(app)
            lowv = str2double(get(findobj(app.ich,'tag','window min edit'), 'String'));
            highv = str2double(get(findobj(app.ich,'tag','window max edit'), 'String'));
            app.lowhigh = [lowv highv];

            % Batch graphics updates
            set(app.ImageAxes2.Children, 'CData', imadjust(app.im2,app.lowhigh));
            set(app.ImageAxes3.Children, 'CData', imadjust(app.im3,app.lowhigh));
        end

        function closegui(app)
            uiresume(app.UIFigure);
        end

        function lowhightemp = outputlowhigh(app)
            lowhightemp = app.lowhigh;
            delete(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, f, frameStack, channelNo)

            tempims = arrayfun(@(i) im2double(f.getimage(i)), [frameStack(1) frameStack(round(end/2)) frameStack(end)], 'UniformOutput', false);
            app.im1 = tempims{1}(:,:,channelNo);
            app.im2 = tempims{2}(:,:,channelNo);
            app.im3 = tempims{3}(:,:,channelNo);

            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            axis(app.ImageAxes3, 'image');

            imh = imshow(app.im1 , 'Parent', app.ImageAxes1);
            imshow(app.im2, 'Parent', app.ImageAxes2);
            imshow(app.im3, 'Parent', app.ImageAxes3);

            app.lowhigh = [0 1]; % Preallocate
            app.ich = imcontrast(imh);

            addlistener(findobj(app.ich,'tag','window min edit'), 'String', 'PostSet', @(~,~) app.updatelowhigh);
            addlistener(findobj(app.ich,'tag','window max edit'), 'String', 'PostSet', @(~,~) app.updatelowhigh);
            set(app.ich, 'CloseRequestFcn', @(~,~) app.closegui);
            uiwait(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImageAxes1 = uiaxes(app.UIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.UIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.ImageAxes3 = uiaxes(app.UIFigure);
            app.ImageAxes3.XTick = [];
            app.ImageAxes3.XTickLabel = {'[ ]'};
            app.ImageAxes3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adjustcontrastGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end