classdef drawroiGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RotateAngleSpinner       matlab.ui.control.Spinner
        RotateAngleSpinnerLabel  matlab.ui.control.Label
        SelectROIButton          matlab.ui.control.Button
    end


    properties (Access = public)
        ImageAxesParent
        imgProcessed
        roi
        rotateAngle
        roiPosition
        nRow
        nCol
        nPanel
        channelOrFreqdiv
        rowLabel
        colLabel
        tempImg
    end

    methods (Access = private)

        function createPanels(app, postInfo)

            screenSize = get(groot, 'ScreenSize');

            app.roiPosition = [];
            app.rotateAngle = 0;

            left = 80;
            top = 40;
            intervalRight = 20;
            intervalBot = 20;
            tempSpace = 70;  % leave for other button

            rowLabelHeight = 90;
            colLabelWidth = 100;

            % Retrieve nChannel and nTime
            nChannel = length(postInfo.exportedChannelNo);
            nFreq = size(postInfo.frames);
            if nChannel == 1
                if all(nFreq~=1)
                    error("Error: Too many dimensions to output.");
                else
                    app.channelOrFreqdiv = false;
                    app.nRow = max(nFreq);
                end
            else
                if any(nFreq~=1)
                    error("Error: Too many dimensions to output.");
                else
                    app.channelOrFreqdiv = true;
                    app.nRow = nChannel;
                end
            end

            nTime = length(postInfo.frames{1});

            % Determine the row and col number

            if nTime < 3
                app.nCol = nTime;
            else
                app.nCol = 3;
            end
            app.nPanel = app.nCol*app.nRow;
            panelHeight = min((screenSize(4) - tempSpace - screenSize(4)/10) / app.nRow-intervalBot, 400);
            panelWidth = panelHeight;

            winWidth = left+(panelWidth+intervalRight)*app.nCol;
            winHeight= top+(panelHeight+intervalBot)*app.nRow + tempSpace;
            bot = winHeight - panelHeight - top; % first panel bot

            iPanel = 0;  % create the panel col by col 一列一列画
            for iCol = 1:app.nCol
                for iRow = 1:app.nRow
                    iPanel = iPanel + 1;
                    app.ImageAxesParent{iPanel} = uiaxes(app.UIFigure);
                    app.ImageAxesParent{iPanel}.XTick = [];
                    app.ImageAxesParent{iPanel}.XTickLabelRotation = 0;
                    app.ImageAxesParent{iPanel}.XTickLabel = {''};
                    app.ImageAxesParent{iPanel}.YTick = [];
                    app.ImageAxesParent{iPanel}.YTickLabelRotation = 0;
                    app.ImageAxesParent{iPanel}.ZTickLabelRotation = 0;
                    app.ImageAxesParent{iPanel}.Position = [left+(panelWidth+intervalRight)*(iCol-1) bot-(panelHeight+intervalBot)*(iRow-1) panelWidth panelHeight];
                end
            end

            % label channel
            for iRow = 1:app.nRow
                app.rowLabel{iRow} = uilabel(app.UIFigure);
                app.rowLabel{iRow}.Text = ['Channel ' num2str(iRow)];
                app.rowLabel{iRow}.Position = [5 bot+panelHeight/2-rowLabelHeight/2-(panelHeight+intervalBot)*(iRow-1) left+5 rowLabelHeight];
                app.rowLabel{iRow}.FontSize = 16;
            end

            % label time
            for iCol = 1:app.nCol
                app.colLabel{iCol} = uilabel(app.UIFigure);
                app.colLabel{iCol}.Position = [left+panelWidth/2-colLabelWidth/2+(panelWidth+intervalRight)*(iCol-1) winHeight-50 colLabelWidth 50];
                app.colLabel{iCol}.FontSize = 16;
            end

            % position rotateAngle
            space = 15;
            spinnerToButton = 50;
            angleLabelWidth = 120;
            angleLabelHeight = 26;
            spinnerWidth = 210;
            spinnerHeight = 38;
            buttonHeight = 42;
            buttonBot = 25;
            buttonWidth = 180;

            app.RotateAngleSpinnerLabel.Position = [left+space buttonBot+buttonHeight/2-angleLabelHeight/2 angleLabelWidth 26];
            app.RotateAngleSpinner.Position = [left+space+angleLabelWidth+space buttonBot+buttonHeight/2-spinnerHeight/2 spinnerWidth 38];

            % position selectROI button
            if app.nCol == 1
                angleComponentHalfWidth = (angleLabelWidth+spinnerWidth)/2;
                app.SelectROIButton.Position = [left+space+angleComponentHalfWidth-buttonWidth/2 buttonBot+buttonHeight+20 buttonWidth buttonHeight];
            else
                app.SelectROIButton.Position = [left+space+angleLabelWidth+spinnerWidth+spinnerToButton buttonBot buttonWidth buttonHeight];
            end

            % position figure at center
            figurePosition = [(screenSize(3)-winWidth)/2, (screenSize(4)-winHeight)/2, winWidth, winHeight];
            app.UIFigure.Position = figurePosition;
        end

        function showImg(app, filename, postInfo, contrastMethod)

            switch contrastMethod
                case 0
                    lowhigh = [0 1];
                    lowhigh = repmat(lowhigh, [app.nCol 1]);
                case 1
                    lowhigh = postInfo.autoContrastPara;
                case 2
                    lowhigh = postInfo.manualContrastPara;
                otherwise
                    error('Variable contrastMethod are unavailable.');
            end

            frames = postInfo.frames;
            if app.channelOrFreqdiv
                idx = round(linspace(1,length(frames{1}),app.nCol));
                time = frames{1}(idx);
            else
                time = zeros(length(frames), app.nCol);
                for iFreq = 1:length(frames)
                    idx = round(linspace(1,length(frames{iFreq}),app.nCol));
                    time(iFreq, :) =  frames{iFreq}(idx);
                end
            end

            f = Nd2Reader(filename);

            % show image
            iPanel = 0;
            for iCol = 1:app.nCol
                for iRow = 1:app.nRow
                    iPanel = iPanel+1;
                    if app.channelOrFreqdiv
                        img = f.getimage(time(iCol));
                        app.imgProcessed{iPanel} = imadjust(img(:,:,iRow), lowhigh{1}(iRow, :));
                        imshow(app.imgProcessed{iPanel}, 'Parent', app.ImageAxesParent{iPanel});

                        app.colLabel{iCol}.Text = ['Frame =  ' num2str(time(iCol))];
                    else
                        app.imgProcessed{iPanel}= imadjust(f.getimage(time(iRow, iCol)), lowhigh{iRow,:});
                        imshow(app.imgProcessed{iPanel}, 'Parent', app.ImageAxesParent{iPanel});

                        app.colLabel{iCol}.Text = ['Frame =  ' num2str(time(iRow, iCol))];
                    end
                end
            end
            f.close();
        end

        function resetroi(app)
            if ~isempty(app.roi)
                for iPanel = 1:app.nPanel
                    delete(app.roi{iPanel});
                end
            end
        end

        function updateroi(app, iPanel)
            %             disp('update1');
            app.roiPosition = app.roi{iPanel}.Position;
            app.updateroiall();
        end

        function updateroiall(app)
            %             disp('updateall');
            for iPanel = 1:app.nPanel
                app.roi{iPanel}.Position = app.roiPosition;
            end
        end

    end

    methods (Access = public)

        function [rotateAngle, roiPosition, cropSize] = getroi(app)
            roiPosition = app.roiPosition;
            rotateAngle = app.rotateAngle;
            if isempty(roiPosition)
                cropSize = size(app.imgProcessed{1});
            else
                cropSize = size(imcrop(app.imgProcessed{1}, roiPosition));
            end
            delete(app);  % important!! close window after grabbing data
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, filename, postInfo, contrastMethod)
            createPanels(app,postInfo);
            showImg(app, filename, postInfo, contrastMethod);
            uiwait(app.UIFigure);
        end

        % Value changed function: RotateAngleSpinner
        function RotateAngleSpinnerValueChanged(app, event)
            app.rotateAngle = app.RotateAngleSpinner.Value;
            for iPanel = 1:app.nPanel
                rotatedImage = imrotate(app.imgProcessed{iPanel}, app.rotateAngle,'bicubic','crop');
                app.resetroi();
                imshow(rotatedImage, 'Parent', app.ImageAxesParent{iPanel});
            end
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            app.resetroi();
            app.roi{1} = drawrectangle(app.ImageAxesParent{1});
            app.roiPosition = app.roi{1}.Position; % draw roi in 1st panel
            % draw other panel
            for iPanel = 2:app.nPanel
                app.roi{iPanel} = drawrectangle(app.ImageAxesParent{iPanel}, 'Position', app.roiPosition);
            end

            % move the rest panel when one panel moves
            for iPanel = 1:app.nPanel
                addlistener(app.roi{iPanel}, 'ROIMoved', @(~,~) app.updateroi(iPanel)); % check with events(roi)
            end
            uiwait(app.UIFigure);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.UIFigure); % important!! grab data when click "close window"
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Text = 'Select ROI';
            app.RotateAngleSpinnerLabel = uilabel(app.UIFigure);
            app.RotateAngleSpinnerLabel.Text = 'Rotate Angle';
            app.RotateAngleSpinner = uispinner(app.UIFigure);
            app.RotateAngleSpinner.ValueChangedFcn = createCallbackFcn(app, @RotateAngleSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drawroiGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end