classdef v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AutoContrastCheckBox            matlab.ui.control.CheckBox
        ConcatenateCheckBox             matlab.ui.control.CheckBox
        ScalebarCheckBox                matlab.ui.control.CheckBox
        WithTextCheckBox                matlab.ui.control.CheckBox
        TimeStampCheckBox               matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        PreviewButton                   matlab.ui.control.Button
        ManualContrastButton            matlab.ui.control.Button
        LoadParametersButton            matlab.ui.control.Button
        LoadingLabel                    matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        UploadFilenameButton            matlab.ui.control.Button
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        SavepathEditFieldLabel          matlab.ui.control.Label
        SavepathEditField               matlab.ui.control.EditField
        ObjectivesEditFieldLabel        matlab.ui.control.Label
        ObjectivesEditField             matlab.ui.control.NumericEditField
        ChannelNumberEditFieldLabel     matlab.ui.control.Label
        ChannelNumberEditField          matlab.ui.control.NumericEditField
        CountEveryFramesEditFieldLabel  matlab.ui.control.Label
        CountEveryFramesEditField       matlab.ui.control.NumericEditField
        SelectedChannelListBoxLabel     matlab.ui.control.Label
        SelectedChannelListBox          matlab.ui.control.ListBox
        SliceEditFieldLabel             matlab.ui.control.Label
        SliceEditField                  matlab.ui.control.EditField
        ShortestSideEditFieldLabel      matlab.ui.control.Label
        ShortestSideEditField           matlab.ui.control.NumericEditField
        Selectatleast1channelLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        para;
        para_checkbox;
        post_info; % Information about original and compressed images.
        img_final;
    end
    
    methods (Access = private)
        
        function para = readpara(app)
            %Read parameters
            para.filename = app.FilenameEditField.Value;
            para.savepath = app.SavepathEditField.Value;
            para.obj = app.ObjectivesEditField.Value;
            para.output_every_frame = app.CountEveryFramesEditField.Value;
            para.split_channel_num = app.ChannelNumberEditField.Value;
            para.selected_channel = app.SelectedChannelListBox.Value;
            para.output_channel = zeros(1, numel(para.selected_channel));
            for i = 1:numel(para.selected_channel)
                para.output_channel(i) = str2double(para.selected_channel{i});
            end
            para.slice = eval(app.SliceEditField.Value);
            para.shortest_side = app.ShortestSideEditField.Value;
        end
        
        function checkpara(app)
            
            %Check parameters
            filename = app.para.filename;
            try
                imgInfo = ND2Info(filename);
                img_num = imgInfo.numImages;
            catch
                error('Error: File is not supported or unavailble.');
            end
            
            savepath =  app.para.savepath;
            if ~isfolder(savepath)
                error('Error: Savepath is not a folder or does not exist.');
            end
            
            obj = app.para.obj;
            if obj <= 0
                error('Error: Objective must be > 0');
            end
            
            split_channel_num = app.para.split_channel_num;
            if mod(split_channel_num, 1) ~=0 || split_channel_num <=0 || split_channel_num > 4
                error('Error: Channel number must be integer and cannot handle channels > 4 or <= 0');
            end
            
            selected_channel = app.para.selected_channel;
            if isempty(selected_channel)
                error('Error: Must select at least one channel.');
            end
            
            shortest_side = app.para.shortest_side;
            if mod(shortest_side, 1)~= 0 || shortest_side <= 0 || shortest_side > 1024
                error('Error: Shortest side must be integer > 0 and < 1024');
            end
            
            slice = app.para.slice;
            if mod(slice, 1) ~= 0
                error('Error: Slice must be integer array.');
            end
            if ( ~isrow(slice) || size(slice, 2) > 2) && ~isempty(slice)
                error('Error: Slice must be a row vector of size 1 x 2');
            end
            if any(slice <= 0 & slice > img_num)
                error(['Error: Slice must be in the range of ' num2str(img_num)]);
            end
            if length(slice) == 2
                if slice(2) <= slice(1)
                    error('Error: Slice(2) must be larger than Slice(1).');
                end
            end
            
            output_every_frame = app.para.output_every_frame;
            if mod(output_every_frame, 1)~= 0 || output_every_frame <= 0 || output_every_frame > img_num
                error(['Error: Count per frame must be integer > 0 and < ' num2str(img_num)]);
            end
        end
        
        function post_info =  ND2analysis(app)
            
            %Read parameters
            filename = app.para.filename;
            obj = app.para.obj;
            output_every_frame = app.para.output_every_frame;
            split_channel_num = app.para.split_channel_num;
            output_channel = app.para.output_channel;
            slice = app.para.slice;
            shortest_side = app.para.shortest_side;
            
            interval = output_every_frame * split_channel_num;
            imgInfo = ND2Info(filename);
            img_num = imgInfo.numImages; % number of frames of movie
            
            if imgInfo.Experiment.parameters.durationMs == 0 % fast time lapse
                period_original = imgInfo.Experiment.parameters.periodDiff.avg/1000; % unit s
                fps_original = round(1/period_original);
            else % ND acquisition
                period_original = imgInfo.Experiment.parameters.periodMs/1000;
                fps_original = 1/period_original;
            end
            
            output_fps = fps_original / interval; % output fps
            period_final = period_original * interval;
            temp = ND2ReadSingle(filename, 1);
            min_size = min(size(temp));
            
            if min_size <= shortest_side
                scale = 1;
            else
                scale = shortest_side/min_size;
            end
            temp = imresize(temp, scale);
            
            %Ensure frame number of each channel are the same.
            exported_frame = cell(numel(output_channel), 1);
            compare_sample = cell(numel(output_channel), 1);
            for i = 1:numel(output_channel)
                if isempty(slice)
                    exported_frame{i} = output_channel(i):interval:img_num;
                    compare_sample{i} = ND2ReadSingle(filename, output_channel(i));
                elseif length(slice) == 2
                    exported_frame{i} = slice(1)+output_channel(i)-1:interval: slice(2);
                    compare_sample{i} = ND2ReadSingle(filename, slice(1)+output_channel(i)-1);
                elseif length(slice) == 1
                    exported_frame{i} = slice(1)+output_channel(i)-1;
                    compare_sample{i} = ND2ReadSingle(filename, slice(1)+output_channel(i)-1);
                end
            end
            
            minframe = min(cellfun(@numel, exported_frame));
            exported_frame = cellfun(@(x) x(1:minframe), exported_frame, 'UniformOutput', 0);
            exported_frame = uint16(cell2mat(exported_frame));
            
            %Sort the channel by intensity. (largest the first)
            intensity = cellfun(@(x) sum(x(:)), compare_sample);
            [~, i_sort] = sort(intensity, 'descend');
            exported_frame = exported_frame(i_sort, :);
            
            %Auto contrast parameters.
            lowhigh = autocontrastmovie(filename, exported_frame);
            
            %Info of compressed images.
            post_info.resize_scale = scale;  % scale of image resize.
            post_info.scale = 6.5/obj/scale;  % um/px
            post_info.original_fps = fps_original;  % original fps in the movie
            post_info.original_period = period_original;  % s
            post_info.final_fps = output_fps;   % final fps in the movie
            post_info.final_period = period_final; % s
            post_info.output_channel = output_channel;  % export channel
            post_info.compressed_img_size = size(temp);  % export movie size
            post_info.frames = exported_frame;
            post_info.lowhigh_auto = lowhigh;  % auto contrast parameter for imadjust
            
        end
        
        function [lowv, highv] = readlowhigh(app, im)
            lowv = []; % important to allocate here!
            highv = [];
            imghandle = figure;
            imh = imshow(im);
            ich = imcontrast(imh);
            set(ich, 'CloseRequestFcn',@myClose)
            function myClose(h,~)
                lowv = str2double(get(findobj(h,'tag','window min edit'), 'String'));
                highv = str2double(get(findobj(h,'tag','window max edit'), 'String'));
                delete(h)
            end
            waitfor(ich);
            close(imghandle);
        end
        
        function para = readcheckbox(app)
            para.auto_contrast = app.AutoContrastCheckBox.Value;
            para.cat_ornot = app.ConcatenateCheckBox.Value;
            para.add_scalebar = app.ScalebarCheckBox.Value;
            para.with_text = app.WithTextCheckBox.Value;
            para.add_timestamp = app.TimeStampCheckBox.Value;
        end
        
        function img_compressed = ND2compressor(app, frames)
            
            img_size = app.post_info.compressed_img_size;
            scale = app.post_info.resize_scale;
            filename= app.para.filename;
            %Initialization
            img_compressed = zeros([img_size size(frames, 2) size(frames, 1)], 'uint8');
            [FilePointer, ImagePointer, ImageReadOut] = ND2Open(filename);
            
            if app.para_checkbox.auto_contrast
                lowhigh = app.post_info.lowhigh_auto;
            else
                lowhigh = app.post_info.lowhigh_manual;
            end
            
            for istack = 1:size(frames, 1)
                icurrent = 0;
                f = uifigure;
                d = uiprogressdlg(f,'Title','Compressing the images',...
                    'Message',['Compressing Channel ' num2str(istack)],'Cancelable','on');
                
                for iimg = frames(istack, :)
                    icurrent = icurrent + 1;
                    img = ND2Read(FilePointer, ImagePointer, ImageReadOut, iimg);
                    img = imresize(img, scale); %Resize the image
                    %Contrast stretch image
                    img_compressed(:,:,icurrent, istack) = im2uint8(imadjust(img, lowhigh(istack, :)));
                    d.Value = icurrent/numel(frames(istack, :));
                end
                % Close the dialog box
                close(f);
            end
            
            ND2Close(FilePointer)
            clear('FilePointer')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadFilenameButton
        function UploadFilenameButtonPushed(app, event)
            filterspec = {'*.nd2;*.tif'};
            [f, p] = uigetfile(filterspec);
            fname = [p f];
            if ischar(fname)
                app.FilenameEditField.Value = fname;
                app.SavepathEditField.Value = p;
            end
        end

        % Value changed function: ChannelNumberEditField
        function ChannelNumberEditFieldValueChanged(app, event)
            app.SelectedChannelListBox.Items = {};
            value = app.ChannelNumberEditField.Value;
            if mod(value, 1) ~=0 || value <=0 || value > 4
                errormsg = 'Error: Channel number must be integer and cannot handle channels > 4 or <= 0';
                app.LoadingLabel.Text = errormsg;
                app.LoadingLabel.Visible = true;
                app.LoadingLabel.FontColor = 'r';
                return;
            end
            for i = 1:value
                app.SelectedChannelListBox.Items{i} = num2str(i);
                app.SelectedChannelListBox.Value{i} = num2str(i);
            end
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: AutoContrastCheckBox
        function AutoContrastCheckBoxValueChanged(app, event)
            value = app.AutoContrastCheckBox.Value;
            if value
                app.ManualContrastButton.Enable = false;
            else
                app.ManualContrastButton.Enable = true;
            end
        end

        % Button pushed function: ManualContrastButton
        function ManualContrastButtonPushed(app, event)
            filename = app.FilenameEditField.Value;
            frames = app.post_info.frames;
            lowhigh = zeros(size(frames, 1), 2);
            if size(frames, 2) == 1
                idx_test = 1;
            else
                idx_test = [1 round(size(frames,2)/2) size(frames,2)];
            end
            for istack = 1:size(frames, 1)
                lowestv = 1; highestv = 0;
                for iimg = idx_test
                    im = im2double(ND2ReadSingle(filename, frames(istack, iimg)));
                    [lowv, highv]= readlowhigh(app, im);
                    lowestv = min(lowv, lowestv);
                    highestv = max(highv, highestv);
                end
                lowhigh(istack, :) = [lowestv highestv];
            end
            app.post_info.lowhigh_manual = lowhigh;
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            try
                app.para = readpara(app);
                checkpara(app);
                app.post_info =  ND2analysis(app);
                app.LoadingLabel.Text = 'Loaded Sucessfully!';
                app.LoadingLabel.Visible= true;
                app.LoadingLabel.FontColor = 'k';
            catch ME
                %                                             app.LoadingLabel.Text = 'Failed!';
                app.LoadingLabel.Text = ME.message;
                app.LoadingLabel.Visible = true;
                app.LoadingLabel.FontColor = 'r';
            end
            
        end

        % Value changing function: FilenameEditField
        function FilenameEditFieldValueChanging(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: ObjectivesEditField
        function ObjectivesEditFieldValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: CountEveryFramesEditField
        function CountEveryFramesEditFieldValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: SelectedChannelListBox
        function SelectedChannelListBoxValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changing function: SliceEditField
        function SliceEditFieldValueChanging(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: ShortestSideEditField
        function ShortestSideEditFieldValueChanged(app, event)
            app.LoadingLabel.Visible = false;
            
        end

        % Value changed function: SavepathEditField
        function SavepathEditFieldValueChanged(app, event)
            value = app.SavepathEditField.Value;
            if ~strcmp(value(end), '\')
                app.SavepathEditField.Value(end+1) = '\';
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            app.para_checkbox = readcheckbox(app);
            frames = app.post_info.frames(:,[1 round(end/2) end]);
            app.img_final = ND2compressor(app, frames);
            
            num_stack = size(app.img_final,4);
            num_each_stack = size(app.img_final,3);
            for iimg = 1:num_each_stack
                for istack = 1:num_stack
                    subplot(num_each_stack, num_stack, (iimg-1)*num_stack+istack);
                    imshow(app.img_final(:,:,iimg, istack));
                    title(['#' num2str(frames(istack, iimg))]);
                end
            end
            
            channel_str = 'Channel ';
            for istack = 1:num_stack
                channel_str = [channel_str ' ' num2str(istack)];
            end
            sgtitle(["Preview Panel", channel_str]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutoContrastCheckBox = uicheckbox(app.UIFigure);
            app.AutoContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoContrastCheckBoxValueChanged, true);
            app.AutoContrastCheckBox.Text = 'Auto Contrast';
            app.AutoContrastCheckBox.Value = true;
            app.ConcatenateCheckBox = uicheckbox(app.UIFigure);
            app.ConcatenateCheckBox.Text = 'Concatenate';
            app.ConcatenateCheckBox.Value = true;
            app.ScalebarCheckBox = uicheckbox(app.UIFigure);
            app.ScalebarCheckBox.Text = 'Scalebar';
            app.ScalebarCheckBox.Value = true;
            app.WithTextCheckBox = uicheckbox(app.UIFigure);
            app.WithTextCheckBox.Text = 'With Text';
            app.TimeStampCheckBox = uicheckbox(app.UIFigure);
            app.TimeStampCheckBox.Text = 'Time Stamp';
            app.TimeStampCheckBox.Value = true;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ManualContrastButton = uibutton(app.UIFigure, 'push');
            app.ManualContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ManualContrastButtonPushed, true);
            app.ManualContrastButton.Enable = 'off';
            app.ManualContrastButton.Text = 'Manual Contrast';
            app.LoadParametersButton = uibutton(app.UIFigure, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.LoadingLabel = uilabel(app.UIFigure);
            app.LoadingLabel.Text = 'Loading...';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.UploadFilenameButton = uibutton(app.ParametersPanel, 'push');
            app.UploadFilenameButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilenameButtonPushed, true);
            app.UploadFilenameButton.Text = 'Upload Filename';
            app.FilenameEditFieldLabel = uilabel(app.ParametersPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.ParametersPanel, 'text');
            app.FilenameEditField.ValueChangingFcn = createCallbackFcn(app, @FilenameEditFieldValueChanging, true);
            app.FilenameEditField.Value = 'F:\Exp Data\multilayer_sizechange001.nd2';
            app.SavepathEditFieldLabel = uilabel(app.ParametersPanel);
            app.SavepathEditFieldLabel.Text = 'Savepath';
            app.SavepathEditField = uieditfield(app.ParametersPanel, 'text');
            app.SavepathEditField.ValueChangedFcn = createCallbackFcn(app, @SavepathEditFieldValueChanged, true);
            app.SavepathEditField.Value = 'F:\Exp Data\';
            app.ObjectivesEditFieldLabel = uilabel(app.ParametersPanel);
            app.ObjectivesEditFieldLabel.Text = 'Objectives';
            app.ObjectivesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ObjectivesEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectivesEditFieldValueChanged, true);
            app.ObjectivesEditField.Value = 4;
            app.ChannelNumberEditFieldLabel = uilabel(app.ParametersPanel);
            app.ChannelNumberEditFieldLabel.Text = 'Channel Number';
            app.ChannelNumberEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ChannelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelNumberEditFieldValueChanged, true);
            app.ChannelNumberEditField.Value = 1;
            app.CountEveryFramesEditFieldLabel = uilabel(app.ParametersPanel);
            app.CountEveryFramesEditFieldLabel.Text = 'Count Every Frames';
            app.CountEveryFramesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CountEveryFramesEditField.ValueChangedFcn = createCallbackFcn(app, @CountEveryFramesEditFieldValueChanged, true);
            app.CountEveryFramesEditField.Value = 2;
            app.SelectedChannelListBoxLabel = uilabel(app.ParametersPanel);
            app.SelectedChannelListBoxLabel.Text = 'Selected Channel';
            app.SelectedChannelListBox = uilistbox(app.ParametersPanel);
            app.SelectedChannelListBox.Items = {'1'};
            app.SelectedChannelListBox.Multiselect = 'on';
            app.SelectedChannelListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedChannelListBoxValueChanged, true);
            app.SelectedChannelListBox.Value = {'1'};
            app.SliceEditFieldLabel = uilabel(app.ParametersPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SliceEditField = uieditfield(app.ParametersPanel, 'text');
            app.SliceEditField.ValueChangingFcn = createCallbackFcn(app, @SliceEditFieldValueChanging, true);
            app.SliceEditField.Value = '[]';
            app.ShortestSideEditFieldLabel = uilabel(app.ParametersPanel);
            app.ShortestSideEditFieldLabel.Text = 'Shortest Side';
            app.ShortestSideEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ShortestSideEditField.ValueChangedFcn = createCallbackFcn(app, @ShortestSideEditFieldValueChanged, true);
            app.ShortestSideEditField.Value = 720;
            app.Selectatleast1channelLabel = uilabel(app.ParametersPanel);
            app.Selectatleast1channelLabel.Visible = 'off';
            app.Selectatleast1channelLabel.Text = '(Select at least 1 channel)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end